classdef E3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CurrentXEditFieldLabel        matlab.ui.control.Label
        CurrentXEditField             matlab.ui.control.EditField
        CurrentYEditFieldLabel        matlab.ui.control.Label
        CurrentYEditField             matlab.ui.control.EditField
        StartButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        SanteriSaariokari253345Label  matlab.ui.control.Label
    end


    properties (Access = private)
        Running = false% Is the program running
    end


    methods (Access = private)

        % Code that executes after component creation
        function Setup(app)
            app.Running = false;
            app.StartButton.Text = 'Run';
        end

        % Button pushed function: StartButton
        function startOrStop(app, event)
            % A callback for the Start/Stop button.
            
            if app.Running
                % Animation is currently running. Setting UserData to false stops it.
                app.Running = false;                
            else
                % Animation is not running, so we start it and change the button text to
                % 'Stop'.
                app.Running = true;
                app.StartButton.Text = 'Stop';
                
                % Plot the initial point
                x = [0 0];
                ha = app.UIAxes;
                h = plot(app.UIAxes, x(1), x(2), 'o', 'MarkerSize', 5,...
            	'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
                
                delta = 1;       % The amount to increase/decrease the axes limits
                axis(ha, [-1 1 -1 1]) % Set initial axes limits
            
                % The animation loop continues until UserData becomes false when the user
                % clicks the button.
                step = 0.1;
                while app.Running
                    theta = rand*2*pi; % Generate a random angle
                    x = x + step*[cos(theta) sin(theta)]; % Update coordinates
                    h.XData = x(1); h.YData = x(2); % Update plot
                    
                    % Print the coordinates of the point
                    app.CurrentXEditField.Value = num2str(x(1));
                    app.CurrentYEditField.Value = num2str(x(2));
                    
                    % Check axes limits.
                    if x(1) < ha.XLim(1)
                        ha.XLim(1) = ha.XLim(1) - delta;
                    elseif x(1) > ha.XLim(2)
                        ha.XLim(2) = ha.XLim(2) + delta;
                    end
                    
                    if x(2) < ha.YLim(1)
                        ha.YLim(1) = ha.YLim(1) - delta;
                    elseif x(2) > ha.YLim(2)
                        ha.YLim(2) = ha.YLim(2) + delta;
                    end
                    
                    drawnow % Draw the new plot
                end
                app.StartButton.Text = 'Start'; % Restore the button text to 'Start'
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CurrentXEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X';
            app.CurrentXEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentXEditField.Editable = 'off';
            app.CurrentYEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y';
            app.CurrentYEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentYEditField.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startOrStop, true);
            app.StartButton.Text = 'Start';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Random walk')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SanteriSaariokari253345Label = uilabel(app.UIFigure);
            app.SanteriSaariokari253345Label.Text = 'Santeri Saariokari 253345';
        end
    end

    methods (Access = public)

        % Construct app
        function app = E3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end