classdef E4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CurrentXEditFieldLabel        matlab.ui.control.Label
        CurrentXEditField             matlab.ui.control.EditField
        CurrentYEditFieldLabel        matlab.ui.control.Label
        CurrentYEditField             matlab.ui.control.EditField
        StartButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        MarkerSettingsPanel           matlab.ui.container.Panel
        SizeSpinnerLabel              matlab.ui.control.Label
        SizeSpinner                   matlab.ui.control.Spinner
        MarkerDropDownLabel           matlab.ui.control.Label
        MarkerDropDown                matlab.ui.control.DropDown
        SetColorButton                matlab.ui.control.Button
        DeltaKnobLabel                matlab.ui.control.Label
        DeltaKnob                     matlab.ui.control.Knob
        StepSizeEditFieldLabel        matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        SanteriSaariokari253345Label  matlab.ui.control.Label
    end


    properties (Access = private)
        Running = false % Is the program running
        Hplot % Handle to plot
        Delta = 1 % The amount to increase/decrease the axes limits
        Step = 0.1 % Walker step size
    end


    methods (Access = private)

        % Code that executes after component creation
        function Setup(app)
            app.MarkerSettingsPanel.BackgroundColor = [.8 .8 .8];
            
            app.Running = false;
            app.StartButton.Text = 'Run';
            
            app.Hplot = plot(app.UIAxes, 0, 0, 'o', 'MarkerSize', 5,...
            	'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
         
            app.MarkerDropDown.Items = {'Circle' 'Plus' 'Pentagram'};
            app.MarkerDropDown.ItemsData = {'o' '+' 'p'};
            
            app.SizeSpinner.Limits = [2 50];
            app.SizeSpinner.Step = 1;
            app.SizeSpinner.ValueDisplayFormat = '%.0f';
            app.SizeSpinner.Value = 5;
            
            app.DeltaKnob.MajorTicks = 2:2:20;
            app.DeltaKnob.MinorTicks = 0.5:0.5:20;
            app.DeltaKnob.Limits = [0 20];
            
            app.StepSizeEditField.Value = 0.1;
        end

        % Button pushed function: StartButton
        function startOrStop(app, event)
            % A callback for the Start/Stop button.
            
            if app.Running
                % Animation is currently running. Setting UserData to false stops it.
                app.Running = false;
                
            else
                % Animation is not running, so we start it and change the button text to
                % 'Stop'.
                app.Running = true;
                app.StartButton.Text = 'Stop';
                
                % Plot the initial point
                x = [0 0];
                ha = app.UIAxes;
         
                axis(ha, [-1 1 -1 1]) % Set initial axes limits
            
                % The animation loop continues until UserData becomes false when the user
                % clicks the button.

                while app.Running
                    theta = rand*2*pi; % Generate a random angle
                    x = x + app.Step.*[cos(theta) sin(theta)]; % Update coordinates
                    app.Hplot.XData = x(1); app.Hplot.YData = x(2); % Update plot
                    
                    % Print the coordinates of the point
                    app.CurrentXEditField.Value = num2str(x(1));
                    app.CurrentYEditField.Value = num2str(x(2));
                    
                    % Check axes limits.
                    if x(1) < ha.XLim(1)
                        ha.XLim(1) = ha.XLim(1) - app.Delta;
                    elseif x(1) > ha.XLim(2)
                        ha.XLim(2) = ha.XLim(2) + app.Delta;
                    end
                    
                    if x(2) < ha.YLim(1)
                        ha.YLim(1) = ha.YLim(1) - app.Delta;
                    elseif x(2) > ha.YLim(2)
                        ha.YLim(2) = ha.YLim(2) + app.Delta;
                    end
                    
                    drawnow % Draw the new plot
                end
                app.StartButton.Text = 'Start'; % Restore the button text to 'Start'
            end
        end

        % Button pushed function: SetColorButton
        function setColor(app, event)
            color = uisetcolor;
            if length(color) == 3
                app.Hplot.MarkerFaceColor = color;
            end
        end

        % Value changed function: SizeSpinner
        function changeMarkerSize(app, event)
            app.Hplot.MarkerSize = app.SizeSpinner.Value;
        end

        % Value changed function: DeltaKnob
        function changeDelta(app, event)
            app.Delta = app.DeltaKnob.Value;
        end

        % Value changed function: MarkerDropDown
        function ChangeMarkerType(app, event)
            app.Hplot.Marker = app.MarkerDropDown.Value;            
        end

        % Value changed function: StepSizeEditField
        function changeStepSize(app, event)
            app.Step = app.StepSizeEditField.Value;
            app.StepSizeEditField.Limits = [0 10];
            app.StepSizeEditField.LowerLimitInclusive = false;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CurrentXEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X';
            app.CurrentXEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentXEditField.Editable = 'off';
            app.CurrentYEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y';
            app.CurrentYEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentYEditField.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startOrStop, true);
            app.StartButton.Text = 'Start';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Random walk')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MarkerSettingsPanel = uipanel(app.UIFigure);
            app.MarkerSettingsPanel.Title = 'Marker Settings';
            app.SizeSpinnerLabel = uilabel(app.MarkerSettingsPanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.MarkerSettingsPanel);
            app.SizeSpinner.ValueDisplayFormat = '%.0f';
            app.SizeSpinner.ValueChangedFcn = createCallbackFcn(app, @changeMarkerSize, true);
            app.MarkerDropDownLabel = uilabel(app.MarkerSettingsPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.MarkerSettingsPanel);
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeMarkerType, true);
            app.SetColorButton = uibutton(app.MarkerSettingsPanel, 'push');
            app.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @setColor, true);
            app.SetColorButton.Text = 'Set Color';
            app.DeltaKnobLabel = uilabel(app.UIFigure);
            app.DeltaKnobLabel.Text = 'Delta';
            app.DeltaKnob = uiknob(app.UIFigure, 'continuous');
            app.DeltaKnob.Limits = [0 20];
            app.DeltaKnob.ValueChangedFcn = createCallbackFcn(app, @changeDelta, true);
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @changeStepSize, true);
            app.SanteriSaariokari253345Label = uilabel(app.UIFigure);
            app.SanteriSaariokari253345Label.Text = 'Santeri Saariokari 253345';
        end
    end

    methods (Access = public)

        % Construct app
        function app = E4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end