classdef P3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        LengthofTrackEditFieldLabel     matlab.ui.control.Label
        LengthofTrackEditField          matlab.ui.control.NumericEditField
        GeneratetruetrackblueButton     matlab.ui.control.Button
        GeneratemeasurementredButton    matlab.ui.control.Button
        KalmanfiltergreenButton         matlab.ui.control.Button
        ClearfigureButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ErrorsLabel                     matlab.ui.control.Label
        yvariancesquaredEditFieldLabel  matlab.ui.control.Label
        yvariancesquaredEditField       matlab.ui.control.NumericEditField
        xvariancesquaredEditFieldLabel  matlab.ui.control.Label
        xvariancesquaredEditField       matlab.ui.control.NumericEditField
        ExtendedKalmanblackButton       matlab.ui.control.Button
    end

    properties (Access = private)
        Length % Length of track
        
        HTrack % Handle to track plot
        HMeasurements % Handle to measurements plot
        HKalman % Handle to Kalman filter plot
        HeKalman % Handle to extented kalman's plot
        HEllipse % Handle to error ellipse
                
        Track % Trackpoints
        Measurements % Measurement points
        Kalman % Kalman filter points
        eKalman % Extended Kalman filter's points
        
        Error % [[Measurements' loc] [Kalman's loc] [???] [Kalman's vel]]
        
        Sigmax2 % x-axis' variance
        Sigmay2 % y-axis' variance
        
        Q = [1/3*eye(2),1/2*eye(2);1/2*eye(2),eye(2)]; % Covariance matrix of state model
    end

    methods (Access = private)
    
        function updateErrors(app)
            app.UITable.Data =[{'rms(pos)'; 'max(pos)'; 'median(pos)';'rms(vel)'; 'max(vel)'; 'median(vel)'} num2cell(app.Error)];
        end
    
        function plotCovEllipse(app)
            s=chi2inv(0.68,2);
            a=2*sqrt(app.Sigmax2*s);
            b=2*sqrt(app.Sigmay2*s);
            angle = linspace(0,2*pi);
            x = a*sin(angle);
            y = b*cos(angle);
            
            app.HEllipse = plot(app.UIAxes, x + app.Track(1,end), ...
                y + app.Track(2,end), 'm.');
        end

        function ErrorMat = calcError(app,dataset)
            ErrorMat = nan(6,1);
            if size(dataset,1) == 4
                % Difference between trackpoints' and dataset's values
                err = sqrt(sum((app.Track-dataset).^2));
                % Dataset's rms error
                ErrorMat(1) = sqrt(immse(app.Track(1:2,:), dataset(1:2,:)));
                ErrorMat(4) = sqrt(immse(app.Track(3:4,:), dataset(3:4,:)));
                % Dataset's max error
                ErrorMat(5) = max(err(3:4));
                % Dataset's median of error
                ErrorMat(6) = median(err(3:4));
            else
                err = sqrt(sum((app.Track(1:2,2:end)-dataset).^2));
                ErrorMat(1) = sqrt(immse(app.Track(1:2,2:end), dataset(1:2,:)));
            end
            
            ErrorMat(2) = max(err(1:2));
            ErrorMat(3) = median(err(1:2));
            
            if size(dataset,1) == 4
                ErrorMat(4) = sqrt(immse(app.Track(3:4,:), dataset(3:4,:)));
                ErrorMat(5) = max(err(3:4));
                ErrorMat(6) = median(err(3:4));
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function Setup(app)
            clc;                    % ei tarvii oikeesti
            hold(app.UIAxes,'on');
            clearFigure(app);
        end

        % Button pushed function: GeneratetruetrackblueButton
        function truePushed(app, event)
            clearFigure(app);
            x0 = zeros(4,1); % Mean of initial state
            Phi = [eye(2) eye(2);zeros(2,2) eye(2)];% State transition matrix
            app.Length = app.LengthofTrackEditField.Value; % length of track
            dim = 2+2;% Dimension 2D position and 2D velocity
            app.Track = nan(dim,app.Length+1); % 0 ... Length of track
            app.Track(:,1) = x0;

            for t=1:app.Length
                app.Track(:,t+1)=Phi*app.Track(:,t)+mvnrnd(zeros(1,4),app.Q)';
            end
            
            app.HTrack = plot(app.UIAxes, app.Track(1,:), app.Track(2,:), 'b.-');
        end

        % Button pushed function: ClearfigureButton
        function clearFigure(app, event)
            cla(app.UIAxes);
            app.Error = nan(6,3);
            updateErrors(app);
        end

        % Button pushed function: GeneratemeasurementredButton
        function measurementPushed(app, event)
            if ishandle(app.HTrack)
                H = [eye(2) zeros(2,2)];
                app.Sigmax2 = app.yvariancesquaredEditField.Value;
                app.Sigmay2 = app.xvariancesquaredEditField.Value;
                R = diag([app.Sigmax2,app.Sigmay2]);
                P0 = diag([100 100 1 1]);% Covariance matrix of initial state
                Phi = [eye(2) eye(2);zeros(2,2) eye(2)];% State transition matrix
                x0 = zeros(4,1); % Mean of initial state
                dim = 2+2;% Dimension 2D position and 2D velocity
                app.Measurements = nan(2,app.Length);
                app.Kalman = nan(size(app.Track));
                app.Kalman(:,1)=x0;
                app.eKalman = app.Kalman;
                P = nan(dim,dim,app.Length+1);
                P(:,:,1) = P0;
    
                % Calculates the simulated path based on "original" path
                for t=1:app.Length
                    app.Measurements(:,t)=H*app.Track(:,t+1)+mvnrnd(zeros(1,2),R)';
                end
                
                % Difference between trackpoints and measured values
                err = sqrt(sum((app.Track(1:2,2:end)-app.Measurements).^2));
                
                % Measurements' rms error in location
                app.Error(1,1) = sqrt(immse(app.Track(1:2,2:end), app.Measurements));
                % Measurements' max error in location
                app.Error(2,1) = max(err);
                % Measurements' median of error in location
                app.Error(3,1) = median(err);
                
                measurementsError = calcError(app,app.Measurements);
                app.Error(1:3,1) = measurementsError(1:3,:);
                updateErrors(app);
                
                % The Kalman figure datapoints are calculated but not drawn as 
                % calculations yield the same results every time for a given path
                for t=1:app.Length
                    app.Kalman(:,t+1) = Phi*app.Kalman(:,t); % Prior estimate
                    P(:,:,t+1) = Phi*P(:,:,t)*Phi'+app.Q; % Prior covariance
                    K = P(:,:,t+1)*H'/(H*P(:,:,t+1)*H'+R); % Kalman gain
                    app.Kalman(:,t+1) = app.Kalman(:,t+1)+ ...
                        K*(app.Measurements(:,t)-H*app.Kalman(:,t+1));% Posterior estimate
                    P(:,:,t+1) = (eye(dim)-K*H)*P(:,:,t+1);% Posterior covariance
                end
                
                for t=1:app.Length
                    P_ = Phi*P(:,:,t)*Phi'+app.Q; % 4 x 4
                    Pxx = P_;
                    Pxy = P_*H';
                    Pyx = H*P_;
                    Pyy = H*P_*H'+R;
                    
                    app.eKalman(:,t+1) = Phi*app.Kalman(:,t); % Prior estimate
                    P(:,:,t+1) = Pxx; % Prior covariance
                    app.eKalman(:,t+1) = app.eKalman(:,t+1)+ ...
                        Pxy*(Pyy\(app.Measurements(:,t)-H*app.Track(:,t)));% Posterior estimate
%                         Pxy*inv(Pyy)*(app.Measurements(:,t)-H*app.Track(:,t));% Posterior estimate
                    P(:,:,t+1) = Pxx-Pxy*(Pyy\Pyx);% Posterior covariance
%                     P(:,:,t+1) = Pxx-Pxy*inv(Pyy)*Pyx;% Posterior covariance

                end
                
                % No track to create the path from
                if ~ishandle(app.HTrack)
                    return;
                end
                
                % Deletes the old Kalman fitting based on previous path
                if ishandle(app.HKalman)
                    delete(app.HKalman);
                    delete(app.HeKalman);
                    delete(app.HEllipse);
                    app.Error(:,2) = nan(6,1);
                    app.Error(:,3) = nan(6,1);
                    updateErrors(app);
                end
                
                % Update the plot if it exists, otherwise create one
                if ishandle(app.HMeasurements)
                    app.HMeasurements.XData = app.Measurements(1,:);
                    app.HMeasurements.YData = app.Measurements(2,:);
                else
                    app.HMeasurements = plot(app.UIAxes, app.Measurements(1,:), ...
                        app.Measurements(2,:), 'r.-');
                end
            end
        end

        % Button pushed function: KalmanfiltergreenButton
        function kalmanPushed(app, event)
            if (ishandle(app.HMeasurements))
                app.Error(:,2) = calcError(app,app.Kalman);
                updateErrors(app);
                app.HKalman = plot(app.UIAxes, app.Kalman(1,:),app.Kalman(2,:),'g.-');
                plotCovEllipse(app);
            end
        end

        % Button pushed function: ExtendedKalmanblackButton
        function eKalmanPushed(app, event)
            if (ishandle(app.HMeasurements))
                app.Error(:,3) = calcError(app,app.eKalman);
                updateErrors(app);
                app.HeKalman = plot(app.UIAxes, app.eKalman(1,:),app.eKalman(2,:),'k.-');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Kalman filter')
            xlabel(app.UIAxes, 'x-coordinate')
            ylabel(app.UIAxes, 'y-coordinate')
            app.LengthofTrackEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofTrackEditFieldLabel.Text = 'Length of Track';
            app.LengthofTrackEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofTrackEditField.Limits = [3 Inf];
            app.LengthofTrackEditField.Value = 40;
            app.GeneratetruetrackblueButton = uibutton(app.UIFigure, 'push');
            app.GeneratetruetrackblueButton.ButtonPushedFcn = createCallbackFcn(app, @truePushed, true);
            app.GeneratetruetrackblueButton.Text = 'Generate true track (blue)';
            app.GeneratemeasurementredButton = uibutton(app.UIFigure, 'push');
            app.GeneratemeasurementredButton.ButtonPushedFcn = createCallbackFcn(app, @measurementPushed, true);
            app.GeneratemeasurementredButton.Text = 'Generate measurement (red)';
            app.KalmanfiltergreenButton = uibutton(app.UIFigure, 'push');
            app.KalmanfiltergreenButton.ButtonPushedFcn = createCallbackFcn(app, @kalmanPushed, true);
            app.KalmanfiltergreenButton.Text = 'Kalman filter (green)';
            app.ClearfigureButton = uibutton(app.UIFigure, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @clearFigure, true);
            app.ClearfigureButton.Text = 'Clear figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'Measurements'; 'Kalman (linear)'; 'Kalman (non linear)'};
            app.UITable.RowName = {};
            app.ErrorsLabel = uilabel(app.UIFigure);
            app.ErrorsLabel.Text = 'Errors';
            app.yvariancesquaredEditFieldLabel = uilabel(app.UIFigure);
            app.yvariancesquaredEditFieldLabel.Text = 'y-variance squared';
            app.yvariancesquaredEditField = uieditfield(app.UIFigure, 'numeric');
            app.yvariancesquaredEditField.Limits = [0 Inf];
            app.yvariancesquaredEditField.Value = 4;
            app.xvariancesquaredEditFieldLabel = uilabel(app.UIFigure);
            app.xvariancesquaredEditFieldLabel.Text = 'x-variance squared';
            app.xvariancesquaredEditField = uieditfield(app.UIFigure, 'numeric');
            app.xvariancesquaredEditField.Limits = [0 Inf];
            app.xvariancesquaredEditField.Value = 2;
            app.ExtendedKalmanblackButton = uibutton(app.UIFigure, 'push');
            app.ExtendedKalmanblackButton.ButtonPushedFcn = createCallbackFcn(app, @eKalmanPushed, true);
            app.ExtendedKalmanblackButton.Text = 'Extended Kalman (black)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = P3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end